classdef GPR_GUI_App_copy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        uitoolbar1             matlab.ui.container.Toolbar
        text35_2               matlab.ui.control.Label
        autotime               matlab.ui.control.NumericEditField
        AutoTimevalueLabel     matlab.ui.control.Label
        motorStop_pushb        matlab.ui.control.Button
        MotorControls          matlab.ui.container.ButtonGroup
        AUTOButton             matlab.ui.control.ToggleButton
        REVButton              matlab.ui.control.ToggleButton
        FWDButton              matlab.ui.control.ToggleButton
        metaBscanfile_2        matlab.ui.control.EditField
        metaDataBscan_pushb_2  matlab.ui.control.Button
        Kill                   matlab.ui.control.Button
        AWGFilename            matlab.ui.control.EditField
        GeneratePulse          matlab.ui.control.Button
        pushbutton14           matlab.ui.control.Button
        metafile               matlab.ui.control.EditField
        metaData_pushb         matlab.ui.control.Button
        text35                 matlab.ui.control.Label
        text34                 matlab.ui.control.Label
        pushbutton20           matlab.ui.control.Button
        jitter                 matlab.ui.control.CheckBox
        savemulti              matlab.ui.control.EditField
        text33                 matlab.ui.control.Label
        SaveMulti              matlab.ui.control.Button
        bscan4                 matlab.ui.control.Button
        bscan3                 matlab.ui.control.Button
        BeginMultiChannel      matlab.ui.control.Button
        bscan2                 matlab.ui.control.Button
        bscan1                 matlab.ui.control.Button
        multisweep             matlab.ui.control.Label
        text31                 matlab.ui.control.Label
        text30                 matlab.ui.control.Label
        fhi                    matlab.ui.control.EditField
        flo                    matlab.ui.control.EditField
        text29                 matlab.ui.control.Label
        text28                 matlab.ui.control.Label
        filter                 matlab.ui.control.CheckBox
        bkgr                   matlab.ui.control.CheckBox
        envelope               matlab.ui.control.CheckBox
        drange                 matlab.ui.control.EditField
        text27                 matlab.ui.control.Label
        text25                 matlab.ui.control.Label
        text24                 matlab.ui.control.Label
        pushbutton11           matlab.ui.control.Button
        image_name             matlab.ui.control.EditField
        SaveImage              matlab.ui.control.Button
        text23                 matlab.ui.control.Label
        sweep                  matlab.ui.control.Label
        height                 matlab.ui.control.EditField
        text20                 matlab.ui.control.Label
        speed                  matlab.ui.control.EditField
        text19                 matlab.ui.control.Label
        t_trace                matlab.ui.control.EditField
        text18                 matlab.ui.control.Label
        text17                 matlab.ui.control.Label
        text16                 matlab.ui.control.Label
        edit11                 matlab.ui.control.EditField
        B_scan_pushb           matlab.ui.control.Button
        rel_perm               matlab.ui.control.EditField
        text12                 matlab.ui.control.Label
        N_averages             matlab.ui.control.EditField
        text8                  matlab.ui.control.Label
        EndB_scan              matlab.ui.control.Button
        text7                  matlab.ui.control.Label
        text6                  matlab.ui.control.Label
        text5                  matlab.ui.control.Label
        text4                  matlab.ui.control.Label
        text3                  matlab.ui.control.Label
        text2                  matlab.ui.control.Label
        BeginB_scan            matlab.ui.control.Button
        axes4                  matlab.ui.control.UIAxes
        axes3                  matlab.ui.control.UIAxes
        axes2                  matlab.ui.control.UIAxes
        axes1                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Description
        a % Description
        autoTime% Description
        metaDataBscanFileName% Description
    end
    
    methods (Access = private)
        function checkbox1_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in checkbox1.
            
            % hObject    handle to checkbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox1
            
            handles.checkbox1 = get(hObject,'Value');
            guidata(hObject,handles);
        end
        
        function checkbox2_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in checkbox2.
            
            % hObject    handle to checkbox2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox2
            
            handles.checkbox2 = get(hObject,'Value');
            guidata(hObject,handles);
        end
        
        function edit1_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit2_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit5_Callback(app, hObject, eventdata, handles)
            % hObject    handle to edit5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit5 as text
            %        str2double(get(hObject,'String')) returns contents of edit5 as a double
            
            %acquire from GUI and store handle
            handles.N_traces = str2double(get(hObject,'String'));
            
            guidata(hObject,handles);
        end
        
        function edit5_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
            
            %seting default value
            handles.N_traces = 10;
            guidata(hObject,handles);
        end
        
        function edit8_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
            
            set(hObject, 'String', '0');
        end
        
        function edit9_Callback(app, hObject, eventdata, handles)
            % hObject    handle to edit9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit9 as text
            %        str2double(get(hObject,'String')) returns contents of edit9 as a double
            
            %acquire from GUI and store handle
            handles.N_delay = str2double(get(hObject,'String'));
            
            guidata(hObject,handles);
        end
        
        function edit9_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
            
            %seting default value
            handles.N_delay = .1;
            guidata(hObject,handles);
        end
        
        function init_plots(app, handles)
            %this function makes the plots look nice on a button press
            set(0,'defaultfigurecolor',[1 1 1 ]);
            set(0,'defaultAxesFontSize',16);
            set(0,'defaultlinelinewidth',2);
            set(0,'defaultLegendInterpreter','latex');
            set(0,'defaultAxesTickLabelInterpreter','latex');
            
            % Npics=5;
            % pic = round(rand*(Npics-1) + 1);
            % imgfile=char("upper_left_pictures/pic"+num2str(pic)+".PNG");
            % axes(handles.axes4);
            % imgread=imread(imgfile);
            % imshow(imgread);
            % drawnow;
            
            XLIM_NS = 25; %scan length
            YLIM=15; % magnitude voltage
            XLIM_SCAN = 1.5;%meters
            YLIM_DEPTH = 50;%cm
            %grid on for plot1
            axes(handles.axes1); grid on; hold on;
            xlabel('Time (ns)');
            ylabel('Magnitude (V)');
            %xlim([0,XLIM_NS]);
            %grid on for plot2
            axes(handles.axes2); hold on;
            xlabel('Scan Number');
            ylabel('Time');
        end
        
        function data = preprocess(app, data, time, x, hObject, eventdata, handles)
            
            [n,c] = size(data);
            envelope_ = get(handles.envelope,'value');
            bkgr_ = get(handles.bkgr,'value');
            filter_ = get(handles.filter,'value');
            jitter_ = get(handles.jitter,'value');
            
            if(jitter_)
               tlims = [0.5e-9, 2.5e-9];
               data = remove_trigger_jitter(data,time,tlims);
            end
            
            if(bkgr_)
                data = data - mean(data,2); %bkgr subtraction
            end
            
            if(envelope_)
               data = abs(hilbert(data));
            end
            
            if(filter_)
               flo =  str2double(get(handles.flo,'string')).*1e6;
               fhi =  str2double(get(handles.fhi,'string')).*1e6;
            
               %scrub the data
               tmp = fhi;
            
               fhi = max([fhi,flo,0]);
               flo = min([flo,tmp]);
               if(flo < 0)
                   flo = 0;
               end
               Ts = time(2) - time(1);
               Fs = 1/Ts;
               Fx=x(2)-x(1);
               kx = linspace(-Fx/2,Fx/2,numel(x));
               f = linspace(-Fs/2,Fs/2,n);
            
               Pp_Data = fftshift(fft(data,[],1),1);
            
               Pp_Data = fftshift(fft(Pp_Data,[],2),2);
            
               Pp_Data((abs(f) < app.flo) | (abs(f) > fhi),:)=0;
               KX_MAX=20;
               Pp_Data(:,abs(kx)>KX_MAX)=0;
               data = ifft(ifftshift(Pp_Data,1),[],1);
               data = real(ifft(ifftshift(data,2),[],2));
            end
        end
        
        function pushbutton12_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pushbutton12. fake data
            
            % hObject    handle to pushbutton12 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global large_data;
            global frametimes;
            global time;
            dir = "C:\Users\samwa\OneDrive - UC Davis\2019-04-30_GPR_GUI_v1p1\2019-04-03_DPDImages\";
            S = csvread(dir+ "gen5_nodpd.csv");
            [n,c] = size(S);
            large_data = S;
            time = linspace(0,12.5e-9,n);
            frametimes = 0.25.*ones(1,c);
            
            axes(handles.axes2); hold on;  colormap(gray(2^12))
            cla(handles.axes2)
            imagesc(1:size(large_data,2),time,flipud(single(large_data)));
            xlim([0, size(large_data,2)]);
            ylim([0,max(time)]);
        end
        
        function pushbutton6_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pushbutton6. csvwrite
            
            % hObject    handle to pushbutton6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %N_traces = handles.N_traces;
            %N_averages = handles.N_averages;
            
            %SCOPEREAD Code for communicating with an instrument.
            %
            %   This is the machine generated representation of an instrument control
            %   session. The instrument control session comprises all the steps you are
            %   likely to take when communicating with your instrument. These steps are:
            %
            %       1. Create an instrument object
            %       2. Connect to the instrument
            %       3. Configure properties
            %       4. Write and read data
            %       5. Disconnect from the instrument
            %
            %   To run the instrument control session, type the name of the file,
            %   ScopeRead, at the MATLAB command prompt.
            %
            %   The file, SCOPEREAD.M must be on your MATLAB PATH. For additional information
            %   on setting your MATLAB PATH, type 'help addpath' at the MATLAB command
            %   prompt.
            %
            %   Example:
            %       scoperead;
            %
            %   See also SERIAL, GPIB, TCPIP, UDP, VISA, BLUETOOTH, I2C, SPI.
            %
            
            %   Creation time: 30-Jul-2018 15:47:54
            
            % Find a VISA-GPIB object.
            obj1 = instrfind('Type', 'visa-gpib', 'RsrcName', 'GPIB0::7::INSTR', 'Tag', '');
            
            % Create the VISA-GPIB object if it does not exist
            % otherwise use the object that was found.
            if isempty(obj1)
                obj1 = visa('AGILENT', 'GPIB0::7::INSTR');
            else
                fclose(obj1);
                obj1 = obj1(1)
            end
            
            % Configure instrument object, obj1.
            set(obj1, 'ByteOrder', 'bigEndian');
            
            set(obj1, 'InputBufferSize', 100000);
            
            % Configure instrument object, obj1
            
            set(obj1, 'OutputBufferSize', 512);
            
            % Connect to instrument object, obj1.
            fopen(obj1);
            
            % Communicating with instrument object, obj1.
            fprintf(obj1, 'WAV:FORMAT WORD');
            fprintf(obj1, 'WAV:SOURCE CHAN3');
            fprintf(obj1, 'WAV:BYTEORDER MSBF');
            dataMat = [];
            meanMatrix = [];
            large_data = [];
            %this for loop takes the average of the for loops matrix and makes a matrix
            %of N_samples
            for i = 1:N_traces
                %this for loop creates matrix of a few samples to then be averaged
                for j = 1:N_averages
                    fprintf(obj1, 'WAV:DATA?');
                    data1 = binblockread(obj1, 'int16');
                    dataMat(:,j) = data1;
                end
                meanMatrix = (sum(dataMat,2) / j); %average the matrix of data and place in new column of new matrix
            
            
                %ploting
                data2 = query(obj1, 'WAV:YINC?');
                data3 = query(obj1, 'WAV:XINC?')
                data4 = query(obj1, 'WAV:YOR?');
            
                %x = [1:1004]* str2num(data3);
                meanMatrix = meanMatrix * str2num(data2) + str2num(data4);
                csvwrite(get(handles.savefilename,'string')+".csv", meanMatrix); %temp for Sam
            
                large_data = cat(2,meanMatrix, large_data);
            
                handles.large_data = large_data;
            
                pause(handles.N_delay);
            
                %clear and then plot A-Scan on to first Axis
                cla(handles.axes1);
                axes(handles.axes1); hold on;
                plot(meanMatrix);
            
                %clear and then plot B-Scan on to second Axis
                cla(handles.axes2);
                axes(handles.axes2); hold on;
            
                %[y,x] = size(meanMatrix);
                %fprintf('%d',x);
                imagesc(large_data);
            
                %fprintf('%d', meanMatrix);
            
                %this is to calculate and print percent completed
                completion_percent = strcat(num2str((i/N_traces) * 100), '%');
                set(hObject, 'String', completion_percent);
            
                %save handles
                guidata(hObject,handles);
            
            end
            
            set(hObject, 'String', 'Begin Sweep');
            
            
            % Disconnect all objects.
            fclose(obj1);
            
            % Clean up all objects.
            delete(obj1);
            
            %save handles
            guidata(hObject,handles);
        end
        
        function savefilename_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to savefilename (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function update_bscan(app, hObject, eventdata, handles)
            global large_data;
            global time;
            [n,c] = size(large_data);
            
            cla(handles.axes2);
            axes(handles.axes2); hold on; colormap(gray(2^12))
            imagesc(1:c,time,flipud(large_data));
            xlim([1 c]);
            ylim([0 max(time)]);
        end
        
        
        function results = Meta_file(app, scanx, time, frametimes)
            global scanx;
            global time;
            global frametimes;
            
            fn = get(handles.metafile,'string');
            
            dlmwrite(fn+"-meta_x_t_times.csv", scanx,'delimiter',',');
            dlmwrite(fn+"-meta_x_t_times.csv", time,'delimiter',',','-append');
            dlmwrite(fn+"-meta_x_t_times.csv", frametimes,'delimiter',',','-append');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GPR_GUI_OpeningFcn(app, varargin)
            % --- Executes just before GPR_GUI is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GPR_GUI (see VARARGIN)
            
            % Choose default command line output for GPR_GUI
           
            handles.output = hObject;
            %
            % % Update handles structure
            guidata(hObject, handles);
            app.a = arduino('com3');


            % UIWAIT makes GPR_GUI wait for user response (see UIRESUME)
            uiwait(handles.figure1);
        end

        % Value changed function: N_averages
        function N_averages_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to N_averages (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of N_averages as text
            %        str2double(get(hObject,'String')) returns contents of N_averages as a double
            
            %acquire from GUI and store handle
            handles.N_averages = str2double(get(hObject,'String'));
            
            guidata(hObject,handles);
        end

        % Button pushed function: bscan1
        function bscan1_Callback(app, event)
            % --- Executes on button press in bscan1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to bscan1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global large_data;
            global multi_data;
            global selected_channels;
            
            
            k = min(find(selected_channels=="CH1"));
            if(~isempty(k))
                large_data = multi_data(:,:,k);
                update_bscan(app, hObject, eventdata, handles);
            else
                disp('Channel unused.');
            end
        end

        % Button pushed function: bscan2
        function bscan2_Callback(app, event)
            % --- Executes on button press in bscan2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to bscan2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global large_data;
            global multi_data;
            global selected_channels;
            
            
            k = min(find(selected_channels=="CH2"));
            if(~isempty(k))
                large_data = multi_data(:,:,k);
                update_bscan(app, hObject, eventdata, handles);
            else
                disp('Channel unused.');
            end
        end

        % Button pushed function: bscan3
        function bscan3_Callback(app, event)
            % --- Executes on button press in bscan3.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to bscan3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global large_data;
            global multi_data;
            global selected_channels;
            
            
            k = min(find(selected_channels=="CH3"));
            if(~isempty(k))
                large_data = multi_data(:,:,k);
                update_bscan(app, hObject, eventdata, handles);
            else
                disp('Channel unused.');
            end
        end

        % Button pushed function: bscan4
        function bscan4_Callback(app, event)
            % --- Executes on button press in bscan4.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to bscan4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global large_data;
            global multi_data;
            global selected_channels;
            
            
            k = min(find(selected_channels=="CH4"));
            if(~isempty(k))
                large_data = multi_data(:,:,k);
                update_bscan(app, hObject, eventdata, handles);
            else
                disp('Channel unused.');
            end
        end

        % Button pushed function: SaveImage
        function SaveImage_Callback(app, event)
            % --- Executes on button press in pushbutton10.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global image;
            
            fn = get(handles.image_name,'String');
            csvwrite(fn+".csv", image);
        end

        % Button pushed function: pushbutton11
        function pushbutton11_Callback(app, event)
            % --- Executes on button press in pushbutton11. GENERATE IMAGE
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton11 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global large_data;
            global time;
            global scanx;            
            global image;
            
            h = str2double(get(handles.height,'string')).*0.0254;
            er = str2double(get(handles.rel_perm,'string'));
            %I = BScan_kirchoff_migration(S,h,er,t,x,xC,zC, progress)
            xC = scanx;
            zC = 0.01:5e-3:50e-2;
            
            
            %perform pre-processing
            pp_large_data = preprocess(app, large_data, time,scanx, hObject, eventdata,handles);
            I = BScan_kirchoff_migration2(pp_large_data,h,er,time,scanx,xC,zC,0,1);
            
            image = I;
            
            Ipp = 20.*log10(I); %postprocessed image
            DR = str2double(get(handles.drange,'string')); %dynamic range
            mI = max(max(Ipp));
            cla(handles.axes3);
            axes(handles.axes3); hold on;
            imagesc(xC,zC,Ipp);
            set(gca,'YDir','reverse')
            colormap(gca,'default');
            caxis([mI - DR, mI])
            ylim([0 max(zC)]);
            xlim([min(xC) max(xC)])
            ylabel('Depth, m');
            xlabel('Along-track distance, m');
        end

        % Button pushed function: BeginMultiChannel
        function BeginMultiChannel_Callback(app, event)
            % --- Executes on button press in pushbutton13.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton13 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global selected_channels;
            
            %survey status of buttons
            ch1 = get(handles.ch1,'value');
            ch2 = get(handles.ch2,'value');
            ch3 = get(handles.ch3,'value');
            ch4 = get(handles.ch4,'value');
            
            channels = [ch1 ch2 ch3 ch4];
            names = ["CH1", "CH2", "CH3", "CH4"];
            
            sctr=1;
            for k = 1:numel(channels)
               if(channels(k))
                   selected_channels{sctr} = names{k};
                   sctr=sctr+1;
               end
            end
            N_chan = sctr-1;
            
            set(handles.multisweep,'string','IN SWEEP');
            
            
            obj1 = instrfind('Type', 'visa-gpib', 'RsrcName', 'GPIB0::7::INSTR', 'Tag', '');
            disp('Entering sweep');
            % Create the VISA-GPIB object if it does not exist
            % otherwise use the object that was found.
            if isempty(obj1)
                obj1 = visa('AGILENT', 'GPIB0::7::INSTR');
            else
                fclose(obj1);
                obj1 = obj1(1);
            end
            set(obj1,'InputBufferSize',2^20);
            fopen(obj1);
            
            % Communicating with instrument object, obj1.
            fprintf(obj1, 'DATa:ENCdg ASCII');
            fprintf(obj1, 'DATa:WIDth 8');
            fprintf(obj1, 'DATa:STARt 1');
            fprintf(obj1, 'DATa:STOP 1000000');
            wfmdataq = query(obj1, 'CURVe?');
            ymult = query(obj1, 'WFMPre:YMUlt?');
            xinc = query(obj1, 'WFMPre:XINcr?');
            
            wfmdata = str2double(strsplit(wfmdataq,','));
            ymult = str2double(ymult);
            xinc = str2double(xinc);
            
            %%%%%%%%%%% FOR TEST ONLY
            % dir = "C:\Users\wagner58\Desktop\2019-04-07_ERLVD_Array_X_Y_Sweeps\";
            % S = csvread(dir+ "erlvd_array_y_4_plastic.csv");
            % wfmdata = S(:,1);
            % xinc = 2.44e-11;
            %%%%%%%%%%%%%%%%%%%%%%%%
            
            
            N_averages = handles.N_averages;
            if(~isfloat(handles.N_averages))
                N_averages = str2double(get(handles.N_averages,'string'));
            end
            N_expected_traces=80;
            multi_data_c = N_expected_traces;
            
            t = 0:xinc:(length(wfmdata)-1)*xinc;
            
            
            global multi_data;
            global frametimes;
            global time;
            multi_data = zeros(length(wfmdata),N_expected_traces,N_chan);
            frametimes = zeros(1,N_expected_traces);
            time = t;
            
            traceCtr=1;
            %while(strcmp(get(handles.multisweep,'string'), 'IN SWEEP'))
            while(strcmp(get(handles.multisweep,'string'), 'IN SWEEP') && traceCtr <= 82)
                tic;
            
                for k = 1:N_chan
                    fprintf(obj1, 'DATa:SOUrce ' + string(selected_channels(k))); %uncomment
                    %for proper operation
                    %fprintf('DATa:SOUrce ' + string(selected_channels(k))+'\n');
            
                    dataMat = zeros(length(wfmdata),1);
            
                    for j = 1:N_averages
                        dataMat = dataMat + sscanf(query(obj1, 'CURVe?'),"%f,");
                        %uncomment
                        %dataMat = dataMat + S(:,traceCtr);
                    end
                    meanMatrix = dataMat./N_averages.*ymult;
                    %meanMatrix = dataMat./N_averages;
                    meanMatrix = meanMatrix - mean(meanMatrix);
                    multi_data(:,traceCtr,k) = meanMatrix;
                end
            
                pause(1e-2)
            
                frametimes(traceCtr) = toc;
                traceCtr=traceCtr+1;
            
                if(traceCtr == multi_data_c)
                   tmp = multi_data;
                   multi_data = zeros(length(wfmdata), multi_data_c+20,N_chan);
                   multi_data(:,1:multi_data_c,:)=tmp;
            
                   tmpf = frametimes;
                   frametimes = zeros(1,multi_data_c+20);
                   frametimes(1:multi_data_c) = tmpf;
            
                   multi_data_c=multi_data_c+20;
                end
            end
        end

        % Button pushed function: pushbutton14
        function pushbutton14_Callback(app, event)
            % --- Executes on button press in pushbutton14.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton14 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.multisweep,'string','NOT IN SWEEP');
            
            global multi_data;
            global frametimes;
            global time;
            global scanx;
            [n,c,N_chan] = size(multi_data);
            
            frametimes = frametimes(frametimes~=0);
            
            
            %uncomment
            speed = str2double(get(handles.speed,'string')).*0.0254;
            scanx = cumsum(0.25.*ones(size(frametimes))).*speed;
            
            
            for j = 1:c
                  if(sum(abs(multi_data(:,j,N_chan))) ~= 0)
                        edited_multi_data(:,j,:) = multi_data(:,j,:);
                  end
            end


            multi_data = edited_multi_data;
            [n,c,N_chan] = size(multi_data);
            
            cla(handles.axes2);
            axes(handles.axes2); hold on; colormap(gray(2^12))
            imagesc(1:c,time,flipud(multi_data(:,:,1)));
            xlim([1 c]);
            ylim([0 max(time)]);
            
            
            cla(handles.axes4);
            axes(handles.axes4); hold on;
            xlabel('Time, s');
            histogram(frametimes);
        end

        % Button pushed function: SaveMulti
        function SaveMulti_Callback(app, event)
            % --- Executes on button press in pushbutton19.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton19 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global multi_data;
            global selected_channels;
            
            N_chan = numel(unique(selected_channels));
            basefn = get(handles.savemulti,'string');
            
            for k = 1:N_chan
               data = multi_data(:,:,k);
               fn = basefn+string(selected_channels{k})+".csv";
               csvwrite(fn,data)
            
            end
        end

        % Button pushed function: BeginB_scan
        function BeginB_scan_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            % PUSHBUTTON 1 - SWEEP
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            init_plots(app, handles)
            set(handles.sweep,'string','IN SWEEP');
            
            
            obj1 = instrfind('Type', 'visa-gpib', 'RsrcName', 'GPIB0::7::INSTR', 'Tag', '');
            
            % Create the VISA-GPIB object if it does not exist
            % otherwise use the object that was found.
            if isempty(obj1)
                obj1 = visa('AGILENT', 'GPIB1::7::INSTR');
            else
                fclose(obj1);
                obj1 = obj1(1);
            end
            set(obj1,'InputBufferSize',5400);
            
            fopen(obj1);
            %set channel and format
            fprintf(obj1, 'WAVeform:SOUrce CHANnel1');
            fprintf(obj1, 'WAVeform:FORMat WORD');
            fprintf(obj1, 'WAVeform:BYTeorder LSBfirst');    %want WORD data
            
            type = query(obj1,'WAVeform:TYPE?');
            disp("Acquisition type is " + type);
            xinc = str2double(query(obj1, 'WAVeform:XINCrement?')); %get x increment
            yinc = str2double(query(obj1, 'WAVeform:YINCrement?'));query(obj1,'*OPC?')%get y increment (unused)
            yorg = str2double(query(obj1, 'WAVeform:YORigin?'));   query(obj1,'*OPC?')%get y origin
            yref = str2double(query(obj1, 'WAVeform:YREFerence?'));query(obj1,'*OPC?')
            
            fprintf(obj1, 'WAVeform:DATA?');
            wfmdata = fread(obj1, 2700, 'int16');query(obj1,'*OPC?')
            
            N_averages = handles.N_averages;
            if(~isfloat(handles.N_averages))
                N_averages = str2double(get(handles.N_averages,'string'));
            end
            
            N_expected_samples = 50;
            t = 0:xinc:(length(wfmdata)-1)*xinc;
            global large_data;
            large_data = zeros(length(wfmdata),N_expected_samples);
            global frametimes;
            frametimes = zeros(1,N_expected_samples);
            global time;
            time = t;
            
            axes(handles.axes2);
            ylim([-max(t) 0].*1e9);
            axes(handles.axes1);
            xlim([0 max(t)].*1e9)
            
            %this for loop takes the average of the for loops matrix and makes a matrix
            %of N_samples
            k = 1;
            t_sample = str2double(get(handles.t_trace,'string'));
            image_update_period = 10;
            N_WINDOW=4;
            window = [zeros(N_WINDOW,1);ones(length(wfmdata)-N_WINDOW,1)];
            while (strcmp(get(handles.sweep,'string'), 'IN SWEEP'))
            %while(1)
                tic
                tic
                %this for loop creates matrix of a few samples to then be averaged
                dataMat = single(zeros(1,length(wfmdata)));
                for j = 1:N_averages
                    fprintf(obj1, ':DIGitize:CHANNEL1');
                    fprintf(obj1, ':WAVeform:DATA?');
                    dataMat = dataMat + ((fread(obj1, 2700, 'int16')-yref).*yinc.*window + yorg)';  %required for non-ADC units, but
                    fprintf(obj1,'*CLS');
                end
            
                meanMatrix = dataMat./N_averages;
                meanMatrix = meanMatrix - mean(meanMatrix);
                large_data(:,k) = meanMatrix;
                %guidata(hObject,handles);
            
                %clear and then plot A-Scan on to first Axis
                if(~mod(k,image_update_period))
                    axes(handles.axes1); hold on;
                    cla(handles.axes1);
                    line(t.*1e9,meanMatrix)
                    ytickformat('%.1f')
                    %clear and then plot B-Scan on to second Axis
                    %cla(handles.axes2);
                    axes(handles.axes2); hold on;  colormap(gray(2^12))
                    cla(handles.axes2)
                    imagesc(1:size(large_data,2),1e9.*fliplr(t-max(t)),single(large_data));
                    drawnow;
                end
            
            
                k=k+1;%loop counter
            
                if( k == N_expected_samples)
                   temp = large_data;
                   [~,c] = size(large_data);
                   large_data = zeros(length(wfmdata),c + 20);
                   large_data(:,1:c) = temp;
            
                   temp = frametimes;
                   frametimes = zeros(1,c+20);
                   frametimes(1:c) = temp;
                end
            
                eT = toc;
                pause(t_sample-eT); %normalize each from to t_sample
                frametimes(k) = toc;
            end
        end

        % Button pushed function: pushbutton20
        function pushbutton20_Callback(app, event)
            % --- Executes on button press in pushbutton20.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton20 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global large_data;
            global time;
            global scanx;
            pp_large_data = real(preprocess(app, large_data, time, scanx, hObject, eventdata,handles));
            [n,c] = size(pp_large_data);
            
            cla(handles.axes3);
            axes(handles.axes3); hold on; colormap(gca,gray(2^12));
            imagesc((pp_large_data));
            xlim([1 c]);
            ylim([1 n]);
            mI = min(min(pp_large_data));
            mxI = max(max(pp_large_data));
            caxis([mI mxI])
        end

        % Button pushed function: metaData_pushb
        function metaData_pushb_Callback(app, event)
            % --- Executes on button press in pushbutton21.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton21 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global scanx;
            global time;
            global frametimes;
            
            fn = get(handles.metafile,'string');
            
            dlmwrite(fn+"-meta_x_t_times.csv", scanx,'delimiter',',');
            dlmwrite(fn+"-meta_x_t_times.csv", time,'delimiter',',','-append');
            dlmwrite(fn+"-meta_x_t_times.csv", frametimes,'delimiter',',','-append');
        end

        % Button pushed function: GeneratePulse
        function GeneratePulse_Callback(app, event)
            % --- Executes on button press in pushbutton22. %GENERATE PULSE (AWG)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton22 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global obj2;
            obj2 = visa('agilent', 'TCPIP0::localhost::hislip0::INSTR');
            
            fopen(obj2); %open AWG
            
            %import pulse into segment
            %first pulse - 200 ps (sigma) differential approx DC-2.5GHz
            disp("Reading pulse from " + handles.AWGFilename.String);
            try
                fprintf(obj2,"*RST"); %reset settings
            
                fprintf(obj2,":SOUR:ROSC:RANG RANG1;:SOUR:ROSC:SOUR INT;:OUTP:ROSC:SOUR SCLK1"); %set "Ref Clk Out" switch
                fprintf(obj2,":OUTP:ROSC:SCD 400"); %Set divider by 400 (= 5 MHz)
                fprintf(obj2,":ARM:TRIG:SLOP POS"); %trigger on positive slope
                fprintf(obj2,":ARM:TRIG:LEV 0.25"); %trigger at 0.25V
                fprintf(obj2,":ARM:TRIG:OPER SYNC");%synchronous mode
            
                fprintf(obj2,":INIT:CONT ON");       %set cont mode
                fprintf(obj2,':TRAC1:DEF 1,1280,0'); %define segment with 1280 samples
            
                fprintf(obj2,':OUTP1 ON');           %set output 1 on
                fprintf(obj2,':INIT:IMM');           %turn on immediate
            
                fprintf(obj2,":TRAC1:IMP 1, "+handles.AWGFilename.String+", CSV, IONly, OFF, ALEN");
                fprintf(obj2,':SOUR:FREQ:RAST 64000000000'); %set 64 GS/s sampling rate
                fprintf(obj2,':OUTP1:FILT:FRAT:SCAL 1');
                fprintf(obj2,':INIT:IMM');           %turn on immediate
            
                %IF ISSUE HERE, use instrreset (instrfind)
            catch
                warning('Error importing waveform');
                fclose(obj2);
            end
        end

        % Button pushed function: Kill
        function Kill_Callback(app, event)
            % --- Executes on button press in pushbutton23. %KILL
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton23 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global obj2;
            fprintf(obj2,'ABOR');
            fclose(obj2);
        end

        % Callback function
        function motorFd_pushb_Callback(app, event)
            % --- Executes on button press in pushbutton24.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton24 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global a;
            writeDigitalPin(a,'D26',1);
            writeDigitalPin(a,'D28',0);
        end

        % Button pushed function: motorStop_pushb
        function motorStop_pushb_Callback(app, event)
            % --- Executes on button press in pushbutton25.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton25 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global a;
            writeDigitalPin(a,'D26',0);
            writeDigitalPin(a,'D28',0);
        end

        % Callback function
        function motorRev_pushb_Callback(app, event)
            % --- Executes on button press in pushbutton26.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton26 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global a;
            writeDigitalPin(a,'D26',0);
            writeDigitalPin(a,'D28',1);
        end

        % Button pushed function: EndB_scan
        function EndB_scan_Callback(app, event)
            % --- Executes on button press in pushbutton2.
            %PUSHBUTTON 2 - END SWEEP
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushb    utton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.sweep,'string','NOT IN SWEEP');
            global large_data;
            global frametimes;
            global time;
            global scanx;
            
            t=time;
            LD = [];
            
            frametimes = frametimes(frametimes~=0);
            
            [r,c] = size(large_data);
            for k = 1:c
                if(sum(abs(large_data(:,k))) ~= 0)
                   LD(:,k) =  large_data(:,k);
                else
                    break;
                end
            end
            [r,c] = size(LD);
            large_data = LD;
            
            % temp = frametimes(1:c);
            % frametimes = temp;
            
            speed = str2double(get(handles.speed,'string')).*0.0254;
            h = str2double(get(handles.height,'string')).*0.0254;
            er = str2double(get(handles.rel_perm,'string'));
            t_gnd = 2*h/3e8;
            d = zeros(1,length(t));
            d(t<t_gnd) = 3e8*(t(t<t_gnd) -t_gnd)./2;
            d(t>=t_gnd) = 3e8/sqrt(er)*(t(t>=t_gnd)-t_gnd)./2;
            d=d.*100;
            
            % dX = mode(frametimes)*speed;
            % x = 0:dX:((c-1)*dX);
            x = cumsum(frametimes).*speed;
            
            scanx = x;
            LD_bkgr = large_data - mean(large_data,2);
            %
            % cla(handles.axes3);
            % axes(handles.axes3); hold on;
            % imagesc(x,fliplr(d),flipud(LD_bkgr));
            % set(gca,'YDir','reverse')
            % ylim([min(d) max(d)]);
            % xlim([0 max(x)]);
            % ylabel('Depth, cm');
            % xlabel('Along-track distance, cm');
            
            Npics=5;
            pic = round(rand*(Npics-1) + 1);
            imgfile=char("upper_left_pictures/pic"+num2str(pic)+".PNG");
            axes(handles.axes4);
            imgread=imread(imgfile);
            imshow(imgread);
            drawnow;
        end

        % Button pushed function: B_scan_pushb
        function B_scan_pushb_Callback(app, event)
            % --- Executes on button press in pushbutton3.
            %PUSHBUTTON 3- SAVE IMAGE
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global large_data;
            fn = get(handles.edit11,'String');
            csvwrite(fn+".csv", large_data);
        end

        % Value changed function: rel_perm
        function rel_perm_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to rel_perm (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of rel_perm as text
            %        str2double(get(hObject,'String')) returns contents of rel_perm as a double
            
            %acquire from GUI and store handle
            handles.permittivity = str2double(get(hObject,'String'));
            
            guidata(hObject,handles);
        end

        % Value changed function: image_name
        function image_nameValueChanged(app, event)
            value = app.image_name.Value;
            
        end

        % Callback function
        function AutoButtonValueChanged(app, event)
            value = app.AutoButton.Value;
            if value == 0

             
            end
            
        end

        % Selection changed function: MotorControls
        function MotorControlsSelectionChanged(app, event)
            selectedButton = app.MotorControls.SelectedObject;
           
            if selectedButton == app.AUTOButton; 
            %FD
            app.BeginB_scan_Callback(app,[]);
            writeDigitalPin(app.a,'D26',1);
            writeDigitalPin(app.a,'D28',0);
            pause(app.autoTime)
            app.EndB_scan_Callback(app, []); 
%BCk
            writeDigitalPin(app.a,'D26',0);
            writeDigitalPin(app.a,'D28',0);
            pause(app.autoTime)
            app.AUTOButton.reset;
            %reset selection
            elseif selectedButton == app.REVButton;
            writeDigitalPin(app.a,'D26',0);
            writeDigitalPin(app.a,'D28',1);
            elseif selectedButton == app.FWDButton;
            writeDigitalPin(app.a,'D26',1);
            writeDigitalPin(app.a,'D28',0);
            end 
            selectedButton.BackgroundColor = [0.2,0.5,0.96];
        end

        % Callback function
        function TimeEditFieldValueChanged(app, event)
            app.Time = app.TimeEditField.Value;
            
        end

        % Button pushed function: metaDataBscan_pushb_2
        function metaDataBscan_pushb_2ButtonPushed(app, event)
             % --- Executes on button press in pushbutton3.
            %PUSHBUTTON 3- SAVE IMAGE
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global large_data;
            fn = app.metaDataBscanFileName;
            csvwrite(fn+".csv", large_data);


            % hObject    handle to pushbutton21 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global scanx;
            global time;
            global frametimes;
           
            
            dlmwrite(fn+"-meta_x_t_times.csv", scanx,'delimiter',',');
            dlmwrite(fn+"-meta_x_t_times.csv", time,'delimiter',',','-append');
            dlmwrite(fn+"-meta_x_t_times.csv", frametimes,'delimiter',',','-append');
        end

        % Callback function
        function MotorControlsButtonDown(app, event)
            
        end

        % Value changed function: autotime
        function autotimeValueChanged(app, event)
            app.autoTime = app.autotime.Value;
 
        end

        % Value changed function: metaBscanfile_2
        function metaBscanfile_2ValueChanged(app, event)
            app.metaDataBscanFileName = app.metaBscanfile_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GPR_GUI';
            app.figure1.Tag = 'figure1';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.Tag = 'axes2';
            app.axes3 = uiaxes(app.figure1);
            app.axes3.Tag = 'axes3';
            app.axes4 = uiaxes(app.figure1);
            app.axes4.Tag = 'axes4';
            app.BeginB_scan = uibutton(app.figure1, 'push');
            app.BeginB_scan.ButtonPushedFcn = createCallbackFcn(app, @BeginB_scan_Callback, true);
            app.BeginB_scan.Tag = 'pushbutton1';
            app.BeginB_scan.Text = 'Begin B-Scan';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'UC Davis Ground Penetrating Radar Terminal';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'V2.1.0 July 15, 2020';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Microwave Microsystem Laboratory (MML)';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Signal Trace (A-Scan)';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Raw B-Scan';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Problems? samwagner@ucdavis.edu';
            app.EndB_scan = uibutton(app.figure1, 'push');
            app.EndB_scan.ButtonPushedFcn = createCallbackFcn(app, @EndB_scan_Callback, true);
            app.EndB_scan.Tag = 'pushbutton2';
            app.EndB_scan.Text = 'End B-Scan';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Relative Permittivity';
            app.N_averages = uieditfield(app.figure1, 'text');
            app.N_averages.ValueChangedFcn = createCallbackFcn(app, @N_averages_Callback, true);
            app.N_averages.Tag = 'N_averages';
            app.N_averages.Value = '1';
            app.text12 = uilabel(app.figure1);
            app.text12.Tag = 'text12';
            app.text12.Text = '# of Running Averages';
            app.rel_perm = uieditfield(app.figure1, 'text');
            app.rel_perm.ValueChangedFcn = createCallbackFcn(app, @rel_perm_Callback, true);
            app.rel_perm.Tag = 'rel_perm';
            app.rel_perm.Value = '5.7';
            app.B_scan_pushb = uibutton(app.figure1, 'push');
            app.B_scan_pushb.ButtonPushedFcn = createCallbackFcn(app, @B_scan_pushb_Callback, true);
            app.B_scan_pushb.Tag = 'pushbutton3';
            app.B_scan_pushb.Text = 'Save B-Scan';
            app.edit11 = uieditfield(app.figure1, 'text');
            app.edit11.Tag = 'edit11';
            app.edit11.Tooltip = 'name.csv';
            app.edit11.Value = 'filename';
            app.text16 = uilabel(app.figure1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Focused 2D Image';
            app.text17 = uilabel(app.figure1);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Enter filename to save raw B-scan (.csv is added)';
            app.text18 = uilabel(app.figure1);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Time Between Traces (s)';
            app.t_trace = uieditfield(app.figure1, 'text');
            app.t_trace.Tag = 't_trace';
            app.t_trace.Value = '0.2';
            app.text19 = uilabel(app.figure1);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Actuator speed (inch/s)';
            app.speed = uieditfield(app.figure1, 'text');
            app.speed.Tag = 'speed';
            app.speed.Value = '2';
            app.text20 = uilabel(app.figure1);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Height off ground (in.)';
            app.height = uieditfield(app.figure1, 'text');
            app.height.Tag = 'height';
            app.height.Value = '8';
            app.sweep = uilabel(app.figure1);
            app.sweep.Tag = 'sweep';
            app.sweep.Text = 'NOT IN SWEEP';
            app.text23 = uilabel(app.figure1);
            app.text23.Tag = 'text23';
            app.text23.Text = {'Enter filename to save preprocessed & migrated image'; ''};
            app.SaveImage = uibutton(app.figure1, 'push');
            app.SaveImage.ButtonPushedFcn = createCallbackFcn(app, @SaveImage_Callback, true);
            app.SaveImage.Tag = 'pushbutton10';
            app.SaveImage.Text = 'Save Image';
            app.image_name = uieditfield(app.figure1, 'text');
            app.image_name.ValueChangedFcn = createCallbackFcn(app, @image_nameValueChanged, true);
            app.image_name.Tag = 'image_name';
            app.image_name.Tooltip = 'name.csv';
            app.image_name.Value = 'filename';
            app.pushbutton11 = uibutton(app.figure1, 'push');
            app.pushbutton11.ButtonPushedFcn = createCallbackFcn(app, @pushbutton11_Callback, true);
            app.pushbutton11.Tag = 'pushbutton11';
            app.pushbutton11.Text = 'Generate Image (Takes Time)';
            app.text24 = uilabel(app.figure1);
            app.text24.Tag = 'text24';
            app.text24.Text = 'All files are saved in current working MATLAB folder';
            app.text25 = uilabel(app.figure1);
            app.text25.Tag = 'text25';
            app.text25.Text = 'STATUS:';
            app.text27 = uilabel(app.figure1);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Image Dynamic Range (dB)';
            app.drange = uieditfield(app.figure1, 'text');
            app.drange.Tag = 'drange';
            app.drange.Value = '10';
            app.envelope = uicheckbox(app.figure1);
            app.envelope.Tag = 'envelope';
            app.envelope.Text = 'Envelope?';
            app.bkgr = uicheckbox(app.figure1);
            app.bkgr.Tag = 'bkgr';
            app.bkgr.Text = 'BKGR?';
            app.bkgr.Value = true;
            app.filter = uicheckbox(app.figure1);
            app.filter.Tag = 'filter';
            app.filter.Text = 'Filter?';
            app.text28 = uilabel(app.figure1);
            app.text28.Tag = 'text28';
            app.text28.Text = 'F_Low';
            app.text29 = uilabel(app.figure1);
            app.text29.Tag = 'text29';
            app.text29.Text = 'F_Hi';
            app.flo = uieditfield(app.figure1, 'text');
            app.flo.Tag = 'flo';
            app.flo.Tooltip = 'name.csv';
            app.flo.Value = '0';
            app.fhi = uieditfield(app.figure1, 'text');
            app.fhi.Tag = 'fhi';
            app.fhi.Tooltip = 'name.csv';
            app.fhi.Value = '5000';
            app.text30 = uilabel(app.figure1);
            app.text30.Tag = 'text30';
            app.text30.Text = 'MHz';
            app.text31 = uilabel(app.figure1);
            app.text31.Tag = 'text31';
            app.text31.Text = 'STATUS:';
            app.multisweep = uilabel(app.figure1);
            app.multisweep.Tag = 'multisweep';
            app.multisweep.Text = 'NOT IN SWEEP';
            app.bscan1 = uibutton(app.figure1, 'push');
            app.bscan1.ButtonPushedFcn = createCallbackFcn(app, @bscan1_Callback, true);
            app.bscan1.Tag = 'bscan1';
            app.bscan1.Text = '1';
            app.bscan2 = uibutton(app.figure1, 'push');
            app.bscan2.ButtonPushedFcn = createCallbackFcn(app, @bscan2_Callback, true);
            app.bscan2.Tag = 'bscan2';
            app.bscan2.Text = '2';
            app.BeginMultiChannel = uibutton(app.figure1, 'push');
            app.BeginMultiChannel.ButtonPushedFcn = createCallbackFcn(app, @BeginMultiChannel_Callback, true);
            app.BeginMultiChannel.Tag = 'pushbutton13';
            app.BeginMultiChannel.Text = 'Begin MultiChannel';
            app.bscan3 = uibutton(app.figure1, 'push');
            app.bscan3.ButtonPushedFcn = createCallbackFcn(app, @bscan3_Callback, true);
            app.bscan3.Tag = 'bscan3';
            app.bscan3.Text = '3';
            app.bscan4 = uibutton(app.figure1, 'push');
            app.bscan4.ButtonPushedFcn = createCallbackFcn(app, @bscan4_Callback, true);
            app.bscan4.Tag = 'bscan4';
            app.bscan4.Text = '4';
            app.SaveMulti = uibutton(app.figure1, 'push');
            app.SaveMulti.ButtonPushedFcn = createCallbackFcn(app, @SaveMulti_Callback, true);
            app.SaveMulti.Tag = 'pushbutton19';
            app.SaveMulti.Text = 'Save Multiscan';
            app.text33 = uilabel(app.figure1);
            app.text33.Tag = 'text33';
            app.text33.Text = 'Enter base filename (+ CHX.csv automatically added)';
            app.savemulti = uieditfield(app.figure1, 'text');
            app.savemulti.Tag = 'savemulti';
            app.savemulti.Tooltip = 'name.csv';
            app.savemulti.Value = 'filename';
            app.jitter = uicheckbox(app.figure1);
            app.jitter.Tag = 'jitter';
            app.jitter.Text = 'De-Jitter?';
            app.jitter.Value = true;
            app.pushbutton20 = uibutton(app.figure1, 'push');
            app.pushbutton20.ButtonPushedFcn = createCallbackFcn(app, @pushbutton20_Callback, true);
            app.pushbutton20.Tag = 'pushbutton20';
            app.pushbutton20.Text = 'Pre-Process (B-Scan Only)';
            app.text34 = uilabel(app.figure1);
            app.text34.Tag = 'text34';
            app.text34.Text = 'Preprocessing Options';
            app.text35 = uilabel(app.figure1);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Enter metadata filename (+ meta.csv added)';
            app.metaData_pushb = uibutton(app.figure1, 'push');
            app.metaData_pushb.ButtonPushedFcn = createCallbackFcn(app, @metaData_pushb_Callback, true);
            app.metaData_pushb.Tag = 'pushbutton21';
            app.metaData_pushb.Text = 'Save Metadata';
            app.metafile = uieditfield(app.figure1, 'text');
            app.metafile.Tag = 'metafile';
            app.metafile.Tooltip = 'name.csv';
            app.metafile.Value = 'filename';
            app.pushbutton14 = uibutton(app.figure1, 'push');
            app.pushbutton14.ButtonPushedFcn = createCallbackFcn(app, @pushbutton14_Callback, true);
            app.pushbutton14.Tag = 'pushbutton14';
            app.pushbutton14.Text = 'End MultiChannel';
            app.GeneratePulse = uibutton(app.figure1, 'push');
            app.GeneratePulse.ButtonPushedFcn = createCallbackFcn(app, @GeneratePulse_Callback, true);
            app.GeneratePulse.Tag = 'pushbutton22';
            app.GeneratePulse.Text = 'Generate Pulse (AWG)';
            app.AWGFilename = uieditfield(app.figure1, 'text');
            app.AWGFilename.Tag = 'AWGFilename';
            app.AWGFilename.Tooltip = 'name.csv';
            app.AWGFilename.Value = 'filename';
            app.Kill = uibutton(app.figure1, 'push');
            app.Kill.ButtonPushedFcn = createCallbackFcn(app, @Kill_Callback, true);
            app.Kill.Tag = 'pushbutton23';
            app.Kill.Text = 'Kill';
            app.metaDataBscan_pushb_2 = uibutton(app.figure1, 'push');
            app.metaDataBscan_pushb_2.ButtonPushedFcn = createCallbackFcn(app, @metaDataBscan_pushb_2ButtonPushed, true);
            app.metaDataBscan_pushb_2.Tag = 'pushbutton21';
            app.metaDataBscan_pushb_2.Text = 'Save Meta + B-Scan';
            app.metaBscanfile_2 = uieditfield(app.figure1, 'text');
            app.metaBscanfile_2.ValueChangedFcn = createCallbackFcn(app, @metaBscanfile_2ValueChanged, true);
            app.metaBscanfile_2.Tag = 'metafile';
            app.metaBscanfile_2.Tooltip = 'name.csv';
            app.metaBscanfile_2.Value = 'filename';
            app.MotorControls = uibuttongroup(app.figure1);
            app.MotorControls.SelectionChangedFcn = createCallbackFcn(app, @MotorControlsSelectionChanged, true);
            app.MotorControls.Title = 'Motor Controls';
            app.FWDButton = uitogglebutton(app.MotorControls);
            app.FWDButton.Text = {'FWD'; ''};
            app.FWDButton.Value = true;
            app.REVButton = uitogglebutton(app.MotorControls);
            app.REVButton.Text = 'REV';
            app.AUTOButton = uitogglebutton(app.MotorControls);
            app.AUTOButton.Text = 'AUTO';
            app.motorStop_pushb = uibutton(app.figure1, 'push');
            app.motorStop_pushb.ButtonPushedFcn = createCallbackFcn(app, @motorStop_pushb_Callback, true);
            app.motorStop_pushb.Tag = 'pushbutton25';
            app.motorStop_pushb.Text = 'STOP';
            app.AutoTimevalueLabel = uilabel(app.figure1);
            app.AutoTimevalueLabel.Text = 'Auto Time value';
            app.autotime = uieditfield(app.figure1, 'numeric');
            app.autotime.ValueChangedFcn = createCallbackFcn(app, @autotimeValueChanged, true);
            app.text35_2 = uilabel(app.figure1);
            app.text35_2.Tag = 'text35';
            app.text35_2.Text = 'Enter filename (creates +meta.csv and .csv bscan)';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GPR_GUI_App_copy(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GPR_GUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end